classdef NETQUANT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NETQUANTv1                     matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        displayimagedataButton         matlab.ui.control.Button
        sampletypeDropDownLabel        matlab.ui.control.Label
        sampletypeDropDown             matlab.ui.control.DropDown
        Version130Label                matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        sourceEditFieldLabel           matlab.ui.control.Label
        sourceEditField                matlab.ui.control.EditField
        targetEditFieldLabel           matlab.ui.control.Label
        targetEditField                matlab.ui.control.EditField
        sourcePathButton               matlab.ui.control.Button
        targetPathButton_2             matlab.ui.control.Button
        DNAchannelnameEditFieldLabel   matlab.ui.control.Label
        DNAchannelnameEditField        matlab.ui.control.EditField
        NamingconventionLabel          matlab.ui.control.Label
        NETchannelnameEditFieldLabel   matlab.ui.control.Label
        NETchannelnameEditField        matlab.ui.control.EditField
        controlfoldernameEditFieldLabel  matlab.ui.control.Label
        controlfoldernameEditField     matlab.ui.control.EditField
        ImageinformationLabel          matlab.ui.control.Label
        imagetypeDropDownLabel         matlab.ui.control.Label
        imagetypeDropDown              matlab.ui.control.DropDown
        imageextensionDropDownLabel    matlab.ui.control.Label
        imageextensionDropDown         matlab.ui.control.DropDown
        preparedataButton              matlab.ui.control.Button
        workingLamp                    matlab.ui.control.Lamp
        sizeXEditFieldLabel            matlab.ui.control.Label
        sizeXEditField                 matlab.ui.control.NumericEditField
        sizeYEditFieldLabel            matlab.ui.control.Label
        sizeYEditField                 matlab.ui.control.NumericEditField
        bitdepthEditFieldLabel         matlab.ui.control.Label
        bitdepthEditField              matlab.ui.control.NumericEditField
        pixelsizeEditFieldLabel        matlab.ui.control.Label
        pixelsizeEditField             matlab.ui.control.NumericEditField
        loadpreviousanalysisButton     matlab.ui.control.Button
        workingLamp_3                  matlab.ui.control.Lamp
        loadimageinformationButton     matlab.ui.control.Button
        willloadfirstimagefromcontroldatasetLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        FilepathsLabel                 matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        channel1nameEditFieldLabel     matlab.ui.control.Label
        channel1nameEditField          matlab.ui.control.EditField
        channel2nameEditFieldLabel     matlab.ui.control.Label
        channel2nameEditField          matlab.ui.control.EditField
        DNAchannelDropDownLabel        matlab.ui.control.Label
        DNAchannelDropDown             matlab.ui.control.DropDown
        NETchannelDropDownLabel        matlab.ui.control.Label
        NETchannelDropDown             matlab.ui.control.DropDown
        ChannelorderLabel              matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        batchallButton                 matlab.ui.control.Button
        processallstepsLabel           matlab.ui.control.Label
        workingLamp_9                  matlab.ui.control.Lamp
        Label_17                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        pixelsLabel_2                  matlab.ui.control.Label
        pixelsLabel_3                  matlab.ui.control.Label
        bitsLabel                      matlab.ui.control.Label
        PleaseseemanualforhowimagesshouldbeorganizedLabel  matlab.ui.control.Label
        SegmentationTab                matlab.ui.container.Tab
        methodDropDownLabel            matlab.ui.control.Label
        methodDropDown                 matlab.ui.control.DropDown
        sensitivityEditFieldLabel      matlab.ui.control.Label
        sensitivityEditField           matlab.ui.control.NumericEditField
        iterationsEditFieldLabel       matlab.ui.control.Label
        iterationsEditField            matlab.ui.control.NumericEditField
        calculatedEditFieldLabel       matlab.ui.control.Label
        calculatedEditField            matlab.ui.control.NumericEditField
        usewatershedSwitchLabel        matlab.ui.control.Label
        usewatershedSwitch             matlab.ui.control.ToggleSwitch
        segmentimagesButton            matlab.ui.control.Button
        workingLamp_2                  matlab.ui.control.Lamp
        batchSwitchLabel               matlab.ui.control.Label
        batchSwitch                    matlab.ui.control.Switch
        Label_4                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        SegmentationandlabelsettingsLabel  matlab.ui.control.Label
        to1highervaluecanleadtohigherbackgroundLabel  matlab.ui.control.Label
        toseparatecloseobjectsLabel    matlab.ui.control.Label
        segmentimagesButton_2          matlab.ui.control.Button
        workingLamp_7                  matlab.ui.control.Lamp
        Label_12                       matlab.ui.control.Label
        segmentcontrolsamplesLabel     matlab.ui.control.Label
        pixelsLabel                    matlab.ui.control.Label
        minimumareaEditField_2Label    matlab.ui.control.Label
        minimumareaEditField           matlab.ui.control.NumericEditField
        m2Label                        matlab.ui.control.Label
        ormorehighervalueincreasecomputationtimeLabel  matlab.ui.control.Label
        to1highervaluecanleadtohigherbackgroundLabel_2  matlab.ui.control.Label
        toseparatecloseobjectsLabel_2  matlab.ui.control.Label
        pixelsLabel_4                  matlab.ui.control.Label
        m2Label_2                      matlab.ui.control.Label
        ormorehighervalueincreasecomputationtimeLabel_2  matlab.ui.control.Label
        methodDropDown_2Label          matlab.ui.control.Label
        methodDropDown_2               matlab.ui.control.DropDown
        sensitivityEditField_2Label    matlab.ui.control.Label
        sensitivityEditField_2         matlab.ui.control.NumericEditField
        iterationsEditField_2Label     matlab.ui.control.Label
        iterationsEditField_2          matlab.ui.control.NumericEditField
        calculatedEditField_2Label     matlab.ui.control.Label
        calculatedEditField_2          matlab.ui.control.NumericEditField
        usewatershedSwitch_2Label      matlab.ui.control.Label
        usewatershedSwitch_2           matlab.ui.control.ToggleSwitch
        minimumareaEditField_3Label    matlab.ui.control.Label
        minimumareaEditField_2         matlab.ui.control.NumericEditField
        DNAchannelLabel                matlab.ui.control.Label
        NETchannelLabel                matlab.ui.control.Label
        sensitivityanditerationsonlyapplytoadaptivemethodLabel  matlab.ui.control.Label
        sensitivityanditerationsonlyapplytoadaptivemethodLabel_2  matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        getcellpropertiesButton        matlab.ui.control.Button
        batchSwitch_2Label             matlab.ui.control.Label
        batchSwitch_2                  matlab.ui.control.Switch
        workingLamp_4                  matlab.ui.control.Lamp
        determinethresholdButton       matlab.ui.control.Button
        workingLamp_5                  matlab.ui.control.Lamp
        analysisofcontrolsamplesLabel  matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        CellpropertiesLabel            matlab.ui.control.Label
        NETcriteriaLabel               matlab.ui.control.Label
        cellareafoldincreaseEditField_2Label  matlab.ui.control.Label
        cellareafoldincreaseEditField  matlab.ui.control.NumericEditField
        nucleideformationEditFieldLabel  matlab.ui.control.Label
        nucleideformationEditField     matlab.ui.control.NumericEditField
        DNANETareaEditFieldLabel       matlab.ui.control.Label
        DNANETareaEditField            matlab.ui.control.NumericEditField
        analyzeNETsButton              matlab.ui.control.Button
        workingLamp_8                  matlab.ui.control.Lamp
        Label_14                       matlab.ui.control.Label
        batchSwitch_4Label             matlab.ui.control.Label
        batchSwitch_4                  matlab.ui.control.Switch
        NETsGauge_2Label               matlab.ui.control.Label
        NETsGauge                      matlab.ui.control.SemicircularGauge
        UITable                        matlab.ui.control.Table
        CelldataLabel                  matlab.ui.control.Label
        SummarystatisticsLabel         matlab.ui.control.Label
        cellcountEditField_2Label      matlab.ui.control.Label
        cellcountEditField             matlab.ui.control.NumericEditField
        imagecountEditField_2Label     matlab.ui.control.Label
        imagecountEditField            matlab.ui.control.NumericEditField
        NETsEditField_2Label           matlab.ui.control.Label
        NETsEditField                  matlab.ui.control.NumericEditField
        Label_15                       matlab.ui.control.Label
        controlNETsEditFieldLabel      matlab.ui.control.Label
        controlNETsEditField           matlab.ui.control.NumericEditField
        OutputTab                      matlab.ui.container.Tab
        workingLamp_6                  matlab.ui.control.Lamp
        Label_9                        matlab.ui.control.Label
        batchSwitch_3Label             matlab.ui.control.Label
        batchSwitch_3                  matlab.ui.control.Switch
        outputresultsButton            matlab.ui.control.Button
        csvCheckBox                    matlab.ui.control.CheckBox
        datadistributionandthresholdcutoffsCheckBox  matlab.ui.control.CheckBox
        DatafilesLabel                 matlab.ui.control.Label
        GraphsLabel                    matlab.ui.control.Label
        methodsdescriptionincludingNETcriteriaCheckBox  matlab.ui.control.CheckBox
        TextfilesLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        p = struct; % declare params structure
        cellList = cell(1,1);
        cellNames = cell(1,1);
        cellTable = table;
        currentSampleID = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DNANETareaEditField
        function DNANETareaEditFieldValueChanged(app, event)
            value = app.DNANETareaEditField.Value;
            app.p.ratioInc = value;
        end

        % Value changed function: DNAchannelDropDown
        function DNAchannelDropDownValueChanged(app, event)
            value = app.DNAchannelDropDown.Value;
            value2 = app.channel1nameEditField.Value;
            if strcmp(value,value2)
                app.p.invertChannels = false;
            else
                app.p.invertChannels = true;
            end
        end

        % Value changed function: DNAchannelnameEditField
        function DNAchannelnameEditFieldValueChanged(app, event)
            value = app.DNAchannelnameEditField.Value;
            app.p.nucleiName = value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Value changed function: NETchannelDropDown
        function NETchannelDropDownValueChanged(app, event)
            value = app.NETchannelDropDown.Value;
            value2 = app.channel2nameEditField.Value;
            if strcmp(value,value2)
                app.p.invertChannels = false;
            else
                app.p.invertChannels = true;
            end
        end

        % Value changed function: NETchannelnameEditField
        function NETchannelnameEditFieldValueChanged(app, event)
            value = app.NETchannelnameEditField.Value;
            app.p.netName = value;
        end

        % Value changed function: cellareafoldincreaseEditField
        function areafoldincreaseEditFieldValueChanged(app, event)
            value = app.cellareafoldincreaseEditField.Value;
            app.p.areaInc = value;
        end

        % Button pushed function: batchallButton
        function batchallButtonPushed(app, event)
            %populate p with existing values
            sourcePathValueChanged(app);
            targetPathValueChanged(app);
            controlfoldernameEditFieldValueChanged(app);
            DNAchannelnameEditFieldValueChanged(app);
            NETchannelnameEditFieldValueChanged(app);
            
            
            %set all batch switches to on
            app.batchSwitch.Value = 'On';
            app.batchSwitch_2.Value = 'On';
            app.batchSwitch_3.Value = 'On';
            app.batchSwitch_4.Value = 'On';
            
            %%cycle through samples, start with control
            
            
            %generate experiment and sample lists
            sampleList = getAllFolders (app.p.folder);
            
            %set sample types
            app.sampletypeDropDown.Items = sampleList;
            
            nSample = numel(sampleList);


            prepareDataButtonPushed(app);
            
            %initialize progress bar
            
            progressbar('Overall', 'Segment cells','Get cell properties', 'Analyze NETs', 'Output results');
            
            progressbar(1/nSample,[],[],[],[]);
            
            %make sure control is first
            ind = find(ismember(sampleList, app.controlfoldernameEditField.Value));
            
                
            for iSample = 1:nSample
                app.sampletypeDropDown.Value = app.sampletypeDropDown.Items(iSample);
                sampletypeDropDownValueChanged(app);
                
                
                segmentimagesButtonPushed(app);
                progressbar([], iSample/nSample)
                
               
                getcellpropertiesButtonPushed(app);
               
                progressbar([],[], iSample/nSample)
                
                progressbar(iSample/nSample);
                
            end
          
            NQ_determineThreshold(app.p, [app.p.targetDir filesep app.controlfoldernameEditField.Value]);


            for iSample = 1:nSample
                app.sampletypeDropDown.Value = app.sampletypeDropDown.Items(iSample);
                sampletypeDropDownValueChanged(app);
            
                generateresultsButtonPushed(app);
                progressbar([],[],[], iSample/nSample)
                
                outputresultsButtonPushed(app);
                progressbar([],[],[],[], iSample/nSample)
                
                progressbar(iSample/nSample);
            end
            
            progressbar(1);
            
            app.workingLamp_9.Color='green';
            
        end

        % Value changed function: bitdepthEditField
        function bitdepthEditFieldValueChanged(app, event)
            value = app.bitdepthEditField.Value;
            app.p.bitDepth = value;
        end

        % Value changed function: calculatedEditField
        function calculatedEditFieldValueChanged(app, event)
            value = app.calculatedEditField.Value;
            app.p.minArea{1} = value;
        end

        % Value changed function: calculatedEditField_2
        function calculatedEditField_2ValueChanged(app, event)
            value = app.calculatedEditField_2.Value;
            app.p.minArea{2} = value;
        end

        % Callback function
        function combineconnectedcellsSwitch_2ValueChanged(app, event)
            value = app.combineconnectedcellsSwitch_2.Value;
            if strcmp(value,'On')
                app.p.combineConnected=1;
            else
                app.p.combineConnected=0;
            end
        end

        % Value changed function: controlfoldernameEditField
        function controlfoldernameEditFieldValueChanged(app, event)
            value = app.controlfoldernameEditField.Value;
            app.p.controlPath = value;
        end

        % Button pushed function: determinethresholdButton
        function determinethresholdButtonPushed(app, event)
            
            
            %find correct column of cell names
            columnNames = getAllFolders (app.p.targetDir);
            app.currentSampleID = find(ismember(columnNames, app.controlfoldernameEditField.Value));
            
            
            iExp = app.currentSampleID; %set sample ID to control
            
            currentNames = app.cellNames (:,iExp);
            
            nIm = numel(app.cellList(:,iExp));
            
            for iSample = 1:nIm
                if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(currentNames(iSample),'threshold')
                    
                    NQ_getCellProperties (app.p, app.cellList{iSample,iExp}); %analyze cells
                    
                end
            end
            
            NQ_determineThreshold(app.p, [app.p.targetDir filesep app.controlfoldernameEditField.Value]);
            
            
            app.workingLamp_5.Color='green';
        end

        % Button pushed function: displayimagedataButton
        function displayimagedataButtonPushed(app, event)
            
            %FUTURE add check for processing otherwise error
            
            %populate p with values
            areafoldincreaseEditFieldValueChanged(app);
            nucleideformationEditFieldValueChanged(app);
            DNANETareaEditFieldValueChanged(app);
            
            expFolder = [app.p.targetDir filesep app.sampletypeDropDown.Value filesep app.ListBox.Value];
            NQ_displayImageData (app.p, expFolder);
            
        end

        % Button pushed function: analyzeNETsButton
        function generateresultsButtonPushed(app, event)
            
            sampletypeDropDownValueChanged(app);
            
            %populate p with values
            areafoldincreaseEditFieldValueChanged(app);
            nucleideformationEditFieldValueChanged(app);
            DNANETareaEditFieldValueChanged(app);
            
            
            %set sample ID
            iExp = app.currentSampleID;
            nIm = numel(app.cellList(:,iExp));
            currentNames = app.cellNames (:,iExp);
            
            t=table;
            
            %optional batch function
            if strcmp(app.batchSwitch_4.Value,'On')
                cellCount = 0;
                positiveCells = 0;
                
                for iSample = 1:nIm
                    if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(currentNames(iSample),'threshold')   %check for sample
                        
                        disp(['sampleId: ' num2str(iSample)]);
                        output = NQ_outputData (app.p, app.cellList{iSample,iExp});
                        cellCount = cellCount + output.cellCount;
                        positiveCells = positiveCells + sum(output.positiveCells);
                        percentageNet = positiveCells/cellCount*100;
                        imTable = cell2table({iSample output.cellCount output.percentageNet});
                        t = [t; imTable];
                    end
                end
              output.cellCount = cellCount;
              output.percentageNet = percentageNet;


            elseif ~isempty(app.ListBox.Value)
                
                expFolder = [app.p.targetDir filesep app.sampletypeDropDown.Value filesep app.ListBox.Value];
                output =  NQ_outputData (app.p,expFolder);
               
                nIm = 1;
                iSample = 1;
                imTable = cell2table({iSample output.cellCount output.percentageNet});
                t = [t; imTable];
            elseif isempty(app.ListBox.Value)
                   error('must select a sample from list!'); 
            end
           
            
            %%control analysis
            %set sample ID to control
            
            sampleList = app.sampletypeDropDown.Items;
            index = find(strcmp(sampleList, 'control'));
            
            iExp = index;
            nIm = numel(app.cellList(:,iExp));
            currentNames = app.cellNames (:,iExp);
            
            controlT=table;
            
            %optional batch function
            if strcmp(app.batchSwitch_4.Value,'On')
                cellCount = 0;
                positiveCells = 0;
                
                for iSample = 1:nIm
                    if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(currentNames(iSample),'threshold')   %check for sample
                        
                        disp(['sampleId: ' num2str(iSample)]);
                        controlData = NQ_outputData (app.p, app.cellList{iSample,iExp});
                        cellCount = cellCount + controlData.cellCount;
                        positiveCells = positiveCells + sum(controlData.positiveCells);
                        percentageNet = positiveCells/cellCount*100;
                        imTable = cell2table({iSample controlData.cellCount controlData.percentageNet});
                        controlT = [controlT; imTable];
                    end
                end
              controlData.cellCount = cellCount;
              controlData.percentageNet = percentageNet;
              app.controlNETsEditField.Value = controlData.percentageNet;
            end


            %summary stats
            app.imagecountEditField.Value = nIm;
            app.cellcountEditField.Value = output.cellCount;
          
            app.NETsEditField.Value = output.percentageNet;
            app.NETsGauge.Value = output.percentageNet;


            %table
            app.UITable.Data = [t.Var1 t.Var2 t.Var3];
            
            
            app.workingLamp_8.Color='green';
            
        end

        % Button pushed function: getcellpropertiesButton
        function getcellpropertiesButtonPushed(app, event)
            
            
            %set correct sampleID
            sampletypeDropDownValueChanged(app);
            columnNames = getAllFolders (app.p.targetDir);
            app.currentSampleID = find(ismember(columnNames,app.sampletypeDropDown.Value));
           
            
            %set sample ID
            iExp = app.currentSampleID;
            
            
            if strcmp(app.batchSwitch_2.Value,'On')
                nIm = numel(app.cellList(:,iExp));
                
                for iSample = 1:nIm
                    if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(app.cellList(iSample),'threshold')
                        
                        NQ_getCellProperties (app.p, app.cellList{iSample,iExp}); %analyze cells
                        
                    end
                end
            else
                expFolder = [app.p.targetDir filesep app.sampletypeDropDown.Value filesep app.ListBox.Value];
                
                NQ_getCellProperties (app.p, expFolder); %analyze cells
                
            end
            
            app.workingLamp_4.Color='green';
        end

        % Value changed function: imageextensionDropDown
        function imageextensionDropDownValueChanged(app, event)
            value = app.imageextensionDropDown.Value;
            app.p.extension = value;
        end

        % Value changed function: imagetypeDropDown
        function imagetypeDropDownValueChanged(app, event)
            value = app.imagetypeDropDown.Value;
            app.p.type = value;
        end

        % Value changed function: iterationsEditField
        function iterationsEditFieldValueChanged(app, event)
            value = app.iterationsEditField.Value;
            app.p.iterations{1} = value;
        end

        % Value changed function: iterationsEditField_2
        function iterationsEditField_2ValueChanged(app, event)
            value = app.iterationsEditField_2.Value;
            app.p.iterations{2} = value;
        end

        % Button pushed function: loadimageinformationButton
        function loadimageinformationButtonPushed(app, event)


            %load image
            metadata = NQ_loadImageInfo (app.sourceEditField.Value,'type',...
                app.imagetypeDropDown.Value, 'extension',...
                app.imageextensionDropDown.Value, 'controlName',...
                app.controlfoldernameEditField.Value,'DNAChannelName',...
                app.DNAchannelnameEditField.Value);
            
            
            %set image info
            app.sizeXEditField.Value = metadata.imSizeX;
            app.sizeYEditField.Value = metadata.imSizeY;
            app.bitdepthEditField.Value = metadata.bitDepth;
            app.pixelsizeEditField.Value = metadata.pixelSizeX;
            app.channel1nameEditField.Value = metadata.channelName_1;
            app.channel2nameEditField.Value = metadata.channelName_2;
            
            %populate dropdowns
            app.DNAchannelDropDown.Items = {app.channel1nameEditField.Value, app.channel2nameEditField.Value};
            app.NETchannelDropDown.Items = {app.channel1nameEditField.Value, app.channel2nameEditField.Value};
            
            %set default value of channel 2
            app.NETchannelDropDown.Value = app.channel2nameEditField.Value;
            
            %app.workingLamp.Color='green';
            
        end

        % Button pushed function: loadpreviousanalysisButton
        function loadpreviousanalysisButtonPushed(app, event)
            %populate p with existing values %%potentially change to get values directly
            sourcePathValueChanged(app);
            targetPathValueChanged(app);
            controlfoldernameEditFieldValueChanged(app);
            DNAchannelnameEditFieldValueChanged(app);
            NETchannelnameEditFieldValueChanged(app);
            
            %%generate experiment and sample lists
            expFolders = getAllFolderPaths (app.p.targetDir); %experiment folders
            
            %sample folders and names
            for iExp = 1:numel(expFolders)
                expList{1,iExp} = expFolders{iExp};
                sampleFolders = getAllFolderPaths (expFolders{iExp});
                sampleNames = getAllFolders (expFolders{iExp});
                
                for iSample = 1:numel(sampleFolders)
                    sampleList{iSample,iExp} = sampleFolders{iSample};
                    nameList{iSample,iExp} = sampleNames{iSample};
                end
                
            end
            
            %set sample types
            app.sampletypeDropDown.Items = getAllFolders (app.p.targetDir);
            
            %save sample lists
            names = nameList; %store for use further down
            
            sampleList( cellfun(@isempty, sampleList) ) = {'NA'};
            nameList( cellfun(@isempty, nameList) ) = {'NA'};
            app.cellList = sampleList;
            app.cellNames = nameList;
            app.cellTable = cell2table(sampleList);
            
            %send to listbox
            defaultNames = names (:,1);
            defaultNames = defaultNames(~cellfun('isempty',defaultNames));
            app.ListBox.Items = defaultNames;
            
            %update list
            sampletypeDropDownValueChanged(app);
            
            disp('sample list loaded.');
            
            app.workingLamp.Color='green';
            app.workingLamp_3.Color='green';
        end

        % Value changed function: methodDropDown
        function methodDropDownValueChanged(app, event)
            value = app.methodDropDown.Value;
            app.p.method{1} = value;
        end

        % Value changed function: methodDropDown_2
        function methodDropDown_2ValueChanged(app, event)
            value = app.methodDropDown_2.Value;
            app.p.method{2} = value;
        end

        % Value changed function: minimumareaEditField
        function minimumareaEditFieldValueChanged(app, event)
            value = app.minimumareaEditField.Value;
            pixels = value./app.pixelsizeEditField.Value^2;
            app.calculatedEditField.Value = round(pixels,0);
        end

        % Value changed function: minimumareaEditField_2
        function minimumareaEditField_2ValueChanged(app, event)
            value = app.minimumareaEditField_2.Value;
            pixels = value./app.pixelsizeEditField.Value^2;
            app.calculatedEditField_2.Value = round(pixels,0);
        end

        % Value changed function: nucleideformationEditField
        function nucleideformationEditFieldValueChanged(app, event)
            value = app.nucleideformationEditField.Value;
            app.p.deformationInc = value;
        end

        % Button pushed function: outputresultsButton
        function outputresultsButtonPushed(app, event)
            
            sampletypeDropDownValueChanged(app);
            
            %set correct sampleID
            columnNames = getAllFolders (app.p.targetDir);
            app.currentSampleID = find(ismember(columnNames,app.sampletypeDropDown.Value));
           
            
            %set sample ID
            iExp = app.currentSampleID;
            currentNames = app.cellNames (:,iExp);
            nIm = numel(app.cellList(:,iExp));
            
            data = [];
            if strcmp(app.batchSwitch_3.Value,'On')
                
                
                for iSample = 1:nIm
                    if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(currentNames(iSample),'threshold')
                        
                       [~, newdata] = NQ_loadStruct (app.cellList{iSample,iExp},'data');  %load data
                       
                       if newdata.cellCount==0
                           %don't add anything
                       else
                           data = [data newdata];
                       end
           
                    end
                end
            else
                nIm=1;
                expFolder = [app.p.targetDir filesep app.sampletypeDropDown.Value filesep app.ListBox.Value];
                
                [~, data]  = NQ_loadStruct (expFolder,'data'); %load single data set
                
            end


            %organize data
            nIm = numel(data);
            T=[];
            for iSample = 1:nIm
                %aggreagate data
                T2 = data(iSample).table;
                T = [T;T2];
            end
            
            %create summary stats
            G = groupsummary(T,'NETpositive', {'mean','median','std','sum'}, 4:11);
            
            %all cells
            G2 = summary(T);
            S = table;
            S.median_dnaArea=G2.dnaArea.Median;
            S.median_netArea=G2.netArea.Median;
            S.median_ratioArea=G2.ratioArea.Median;
            S.median_dnaCircularity=G2.dnaCircularity.Median;
            
            
            %write data and text files
            
            if app.csvCheckBox.Value == 1
                writetable(T,[app.p.targetDir filesep 'results_datatable_' app.sampletypeDropDown.Value '.csv']);
                writetable(G,[app.p.targetDir filesep 'results_summary_NETs_' app.sampletypeDropDown.Value '.csv']);
                writetable(S,[app.p.targetDir filesep 'results_summary_all_' app.sampletypeDropDown.Value '.csv']);
            end
            
            if app.methodsdescriptionincludingNETcriteriaCheckBox.Value == 1
                %write text document
                methodText = fopen([app.p.targetDir filesep 'method.txt'],'wt');
                string1= '1.21'; 
                string2= 'Mohanty et al, 2018';
                varA = app.cellareafoldincreaseEditField.Value;
                varB = app.nucleideformationEditField.Value;
                varC = app.DNANETareaEditField.Value;
                
                %stringHeader = 'Quantification of extracellular traps\n';
                stringText = ['Quantification of extracellular traps\n'...
                    'To quantify NETs, the freely available software NETQUANT version %s '...
                    'was used [%s].'...
                    'The software uses thresholds for NET criteria that are set by the user, '...
                    'and for the analysis applied here the thresholds were: '...
                    'cell area fold increase %.2f; nuclei deformation %.2f; dna/net area %.2f.'];
                fprintf(methodText, stringText,string1,string2,varA,varB,varC);
                fclose(methodText);
            end


            %graphs, netArea; ratioArea; dnaCircularity
            
            if app.datadistributionandthresholdcutoffsCheckBox.Value == 1
                [~, threshold] = NQ_loadStruct([app.p.targetDir filesep app.p.controlPath],'threshold');
                
                netT = threshold.medianNetSize*app.cellareafoldincreaseEditField.Value;
                dnaShapeT = app.nucleideformationEditField.Value;
                dnaRatioT = app.DNANETareaEditField.Value;
             
                fig1 = figure;
                hold on
                histogram(T.netArea);
                yL = get(gca,'YLim');
                line([netT netT],yL,'Color','r');
                title('NET area');
                hold off
             
                fig2 = figure;
                hold on
                histogram(T.dnaCircularity);
                yL = get(gca,'YLim');
                line([dnaShapeT dnaShapeT],yL,'Color','r');
                title('DNA shape, 1 = circular');
                hold off
                
                fig3 = figure;
                hold on
                histogram(T.ratioArea);
                yL = get(gca,'YLim');
                line([dnaRatioT dnaRatioT],yL,'Color','r');
                title('DNA/NET ratio');
                hold off
                
                fig4 = figure;
              
                h3 = histogram2 (T.netArea, T.dnaEccentricity);
                h3.FaceColor = 'flat';
                colorbar;
                title('NET area vs DNA shape');


                %save figures
                saveas (fig1,[app.p.targetDir filesep 'results_net_area_distribution_' app.sampletypeDropDown.Value '.pdf']);
                saveas (fig2,[app.p.targetDir filesep 'results_dna_shape_distribution_' app.sampletypeDropDown.Value '.pdf']);
                saveas (fig3,[app.p.targetDir filesep 'results_dna_net_ratio_distribution_' app.sampletypeDropDown.Value '.pdf']);
                saveas (fig4,[app.p.targetDir filesep 'results_bivariate_distribution_' app.sampletypeDropDown.Value '.pdf']);
            end
            
            
            app.workingLamp_6.Color='green';


        end

        % Value changed function: pixelsizeEditField
        function pixelsizeEditFieldValueChanged(app, event)
            valueA = app.pixelsizeEditField.Value;
            valueB = app.minimumareaEditField.Value;
            valueC = app.minimumareaEditField_2.Value;
            pixelsA = valueB./valueA^2;
            pixelsB = valueC./valueA^2;
            app.calculatedEditField.Value = round(pixelsA,0);
            app.calculatedEditField_2.Value = round(pixelsB,0);
        end

        % Button pushed function: preparedataButton
        function prepareDataButtonPushed(app, event)
            
            
            %populate p with existing values %%potentially change to get values directly
            sourcePathValueChanged(app);
            targetPathValueChanged(app);
            controlfoldernameEditFieldValueChanged(app);
            DNAchannelnameEditFieldValueChanged(app);
            NETchannelnameEditFieldValueChanged(app);
            DNAchannelDropDownValueChanged(app);
            NETchannelDropDownValueChanged(app);
            sizeXEditFieldValueChanged(app);
            sizeYEditFieldValueChanged(app);
            bitdepthEditFieldValueChanged(app);
            imageextensionDropDownValueChanged(app);
            imagetypeDropDownValueChanged(app);
            
            %check for inverted channels
            value = app.DNAchannelDropDown.Value;
            value2 = app.channel1nameEditField.Value;
            if strcmp(value,value2)
                app.p.invertChannels = false;
            else
                app.p.invertChannels = true;
            end
                
                
            %prepare data
            NQ_prepareData (app.p.folder, app.p.targetDir, app.p, 'preview', false, 'type',...
                app.imagetypeDropDown.Value, 'extension',...
                app.imageextensionDropDown.Value);
            
            %convert images
            NQ_convertImages (app.p, app.p.targetDir);
            
            
            %generate experiment and sample lists
            expFolders = getAllFolderPaths (app.p.targetDir); %experiment folders
            
            %sample folders and names
            for iExp = 1:numel(expFolders)
                expList{1,iExp} = expFolders{iExp};
                sampleFolders = getAllFolderPaths (expFolders{iExp});
                sampleNames = getAllFolders (expFolders{iExp});
                
                for iSample = 1:numel(sampleFolders)
                    sampleList{iSample,iExp} = sampleFolders{iSample};
                    nameList{iSample,iExp} = sampleNames{iSample};
                end
                
            end
            
            %set sample types
            app.sampletypeDropDown.Items = getAllFolders (app.p.targetDir);
            
            %save sample lists
            names = nameList; %store for use further down
            
            sampleList( cellfun(@isempty, sampleList) ) = {'NA'};
            nameList( cellfun(@isempty, nameList) ) = {'NA'};
            app.cellList = sampleList;
            app.cellNames = nameList;
            app.cellTable = cell2table(sampleList);
            
            %send to listbox
            defaultNames = names (:,1);
            defaultNames = defaultNames(~cellfun('isempty',defaultNames));
            app.ListBox.Items = defaultNames;
            
            disp('sample list created.');
            
            app.workingLamp.Color='green';
            app.workingLamp_3.Color='green';
            
        end

        % Value changed function: sampletypeDropDown
        function sampletypeDropDownValueChanged(app, event)
            value = app.sampletypeDropDown.Value;
            
            %find correct column of cell names
            columnNames = getAllFolders (app.p.targetDir);
            app.currentSampleID = find(ismember(columnNames, value));
            
            %read cellnames fron list
            currentNames = app.cellNames (:,app.currentSampleID);
            currentNames = currentNames(cellfun('isempty',regexp(currentNames,'NA'))); %remove NA from list
            
            %remove threshold if exist
            currentNames = currentNames(cellfun('isempty',regexp(currentNames,'threshold'))); %remove from list
            
            app.ListBox.Items = currentNames;
        end

        % Button pushed function: segmentimagesButton
        function segmentimagesButtonPushed(app, event)
            
            
            %populate p with values
            %channel 1
            methodDropDownValueChanged(app);
            sensitivityEditFieldValueChanged(app);
            iterationsEditFieldValueChanged(app);
            minimumareaEditFieldValueChanged(app);
            calculatedEditFieldValueChanged(app);
            usewatershedSwitchValueChanged(app);
            
            %channel 2
            methodDropDown_2ValueChanged(app);
            sensitivityEditField_2ValueChanged(app);
            iterationsEditField_2ValueChanged(app);
            minimumareaEditField_2ValueChanged(app);
            calculatedEditField_2ValueChanged(app);
            usewatershedSwitch_2ValueChanged(app);
            
            %set sample ID
            iE = app.currentSampleID;
            currentNames = app.cellNames (:,iE);
            
            if strcmp(app.batchSwitch.Value,'On')
                nIm = numel(app.cellList(:,iE));
                
                for iS = 1:nIm
                    if ~strcmp(app.cellList{iS,iE},'NA') && ~strcmp(currentNames(iS),'threshold')
                        
                        NQ_segmentImages (app.p, app.cellList{iS,iE}); %segment
                        NQ_labelMasks (app.p, app.cellList{iS,iE}); %label
                        
                    end
                end
            else
                expFolder = [app.p.targetDir filesep app.sampletypeDropDown.Value filesep app.ListBox.Value];
                
                NQ_segmentImages (app.p, expFolder); %segment
                NQ_labelMasks (app.p, expFolder); %label
            end
            
            app.workingLamp_2.Color='green';
            
        end

        % Button pushed function: segmentimagesButton_2
        function segmentimagesButton_2Pushed(app, event)
            
            %populate p with values
            %channel 1
            methodDropDownValueChanged(app);
            sensitivityEditFieldValueChanged(app);
            iterationsEditFieldValueChanged(app);
            minimumareaEditFieldValueChanged(app);
            calculatedEditFieldValueChanged(app);
            usewatershedSwitchValueChanged(app);
            
            %channel 2
            methodDropDown_2ValueChanged(app);
            sensitivityEditField_2ValueChanged(app);
            iterationsEditField_2ValueChanged(app);
            minimumareaEditField_2ValueChanged(app);
            calculatedEditField_2ValueChanged(app);
            usewatershedSwitch_2ValueChanged(app);


            %find correct column of cell names
            columnNames = getAllFolders (app.p.targetDir);
            app.currentSampleID = find(ismember(columnNames, app.controlfoldernameEditField.Value));
            
            
            iExp = app.currentSampleID; %set sample ID to control
            
            currentNames = app.cellNames (:,iExp);
            
            nIm = numel(app.cellList(:,iExp));


            for iSample = 1:nIm
                if ~strcmp(app.cellList{iSample,iExp},'NA') && ~strcmp(currentNames(iSample),'threshold')
                    
                    NQ_segmentImages (app.p, app.cellList{iSample,iExp}); %segment
                    NQ_labelMasks (app.p, app.cellList{iSample,iExp}); %label
                    
                end
            end
            
            
            app.workingLamp_7.Color='green';
            
        end

        % Value changed function: sensitivityEditField
        function sensitivityEditFieldValueChanged(app, event)
            value = app.sensitivityEditField.Value;
            app.p.sensitivity{1} = value;
        end

        % Value changed function: sensitivityEditField_2
        function sensitivityEditField_2ValueChanged(app, event)
            value = app.sensitivityEditField_2.Value;
            app.p.sensitivity{2} = value;
        end

        % Value changed function: sizeXEditField
        function sizeXEditFieldValueChanged(app, event)
            value = app.sizeXEditField.Value;
            app.p.imSizeX = value;
        end

        % Value changed function: sizeYEditField
        function sizeYEditFieldValueChanged(app, event)
            value = app.sizeYEditField.Value;
            app.p.imSizeY = value;
        end

        % Button pushed function: sourcePathButton
        function sourcePathButtonPushed(app, event)
            path = uigetdir;
            app.sourceEditField.Value = path;
            app.p.folder = path;
        end

        % Callback function: sourceEditField, sourceEditField
        function sourcePathValueChanged(app, event)
            sourcePath = app.sourceEditField.Value;
            app.p.folder = sourcePath;
        end

        % Button pushed function: targetPathButton_2
        function targetPathButton_2Pushed(app, event)
            path = uigetdir;
            app.targetEditField.Value = path;
            app.p.targetDir = path;
        end

        % Value changed function: targetEditField
        function targetPathValueChanged(app, event)
            targetPath = app.targetEditField.Value;
            app.p.targetDir = targetPath;
        end

        % Value changed function: usewatershedSwitch
        function usewatershedSwitchValueChanged(app, event)
            value = app.usewatershedSwitch.Value;
            if strcmp(value,'On')
                app.p.watershedOn{1}=1;
            else
                app.p.watershedOn{1}=0;
            end
        end

        % Value changed function: usewatershedSwitch_2
        function usewatershedSwitch_2ValueChanged(app, event)
            value = app.usewatershedSwitch_2.Value;
            if strcmp(value,'On')
                app.p.watershedOn{2}=1;
            else
                app.p.watershedOn{2}=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NETQUANTv1 = uifigure('Visible', 'off');
            app.NETQUANTv1.Name = 'UI Figure';
            app.Panel = uipanel(app.NETQUANTv1);
            app.ListBox = uilistbox(app.Panel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.displayimagedataButton = uibutton(app.Panel, 'push');
            app.displayimagedataButton.ButtonPushedFcn = createCallbackFcn(app, @displayimagedataButtonPushed, true);
            app.displayimagedataButton.Text = 'display image data ';
            app.sampletypeDropDownLabel = uilabel(app.Panel);
            app.sampletypeDropDownLabel.Text = 'sample type';
            app.sampletypeDropDown = uidropdown(app.Panel);
            app.sampletypeDropDown.Items = {'no samples defined'};
            app.sampletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @sampletypeDropDownValueChanged, true);
            app.sampletypeDropDown.Value = 'no samples defined';
            app.Version130Label = uilabel(app.Panel);
            app.Version130Label.Text = 'Version 1.30';
            app.TabGroup = uitabgroup(app.NETQUANTv1);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.sourceEditFieldLabel = uilabel(app.SetupTab);
            app.sourceEditFieldLabel.Text = 'source';
            app.sourceEditField = uieditfield(app.SetupTab, 'text');
            app.sourceEditField.ValueChangedFcn = createCallbackFcn(app, @sourcePathValueChanged, true);
            app.sourceEditField.ValueChangingFcn = createCallbackFcn(app, @sourcePathValueChanged, true);
            app.targetEditFieldLabel = uilabel(app.SetupTab);
            app.targetEditFieldLabel.Text = 'target';
            app.targetEditField = uieditfield(app.SetupTab, 'text');
            app.targetEditField.ValueChangedFcn = createCallbackFcn(app, @targetPathValueChanged, true);
            app.sourcePathButton = uibutton(app.SetupTab, 'push');
            app.sourcePathButton.ButtonPushedFcn = createCallbackFcn(app, @sourcePathButtonPushed, true);
            app.sourcePathButton.Text = 'get path';
            app.targetPathButton_2 = uibutton(app.SetupTab, 'push');
            app.targetPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @targetPathButton_2Pushed, true);
            app.targetPathButton_2.Text = 'get path';
            app.DNAchannelnameEditFieldLabel = uilabel(app.SetupTab);
            app.DNAchannelnameEditFieldLabel.Text = 'DNA channel name';
            app.DNAchannelnameEditField = uieditfield(app.SetupTab, 'text');
            app.DNAchannelnameEditField.ValueChangedFcn = createCallbackFcn(app, @DNAchannelnameEditFieldValueChanged, true);
            app.DNAchannelnameEditField.Value = 'dna';
            app.NamingconventionLabel = uilabel(app.SetupTab);
            app.NamingconventionLabel.Text = 'Naming convention';
            app.NETchannelnameEditFieldLabel = uilabel(app.SetupTab);
            app.NETchannelnameEditFieldLabel.Text = 'NET channel name';
            app.NETchannelnameEditField = uieditfield(app.SetupTab, 'text');
            app.NETchannelnameEditField.ValueChangedFcn = createCallbackFcn(app, @NETchannelnameEditFieldValueChanged, true);
            app.NETchannelnameEditField.Value = 'net';
            app.controlfoldernameEditFieldLabel = uilabel(app.SetupTab);
            app.controlfoldernameEditFieldLabel.Text = 'control folder name';
            app.controlfoldernameEditField = uieditfield(app.SetupTab, 'text');
            app.controlfoldernameEditField.ValueChangedFcn = createCallbackFcn(app, @controlfoldernameEditFieldValueChanged, true);
            app.controlfoldernameEditField.Value = 'control';
            app.ImageinformationLabel = uilabel(app.SetupTab);
            app.ImageinformationLabel.Text = 'Image information';
            app.imagetypeDropDownLabel = uilabel(app.SetupTab);
            app.imagetypeDropDownLabel.Text = 'image type';
            app.imagetypeDropDown = uidropdown(app.SetupTab);
            app.imagetypeDropDown.Items = {'stack', 'separate'};
            app.imagetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @imagetypeDropDownValueChanged, true);
            app.imagetypeDropDown.Value = 'stack';
            app.imageextensionDropDownLabel = uilabel(app.SetupTab);
            app.imageextensionDropDownLabel.Text = 'image extension';
            app.imageextensionDropDown = uidropdown(app.SetupTab);
            app.imageextensionDropDown.Items = {'.nd2', '.jpeg', '.tiff', '.tif'};
            app.imageextensionDropDown.Editable = 'on';
            app.imageextensionDropDown.ValueChangedFcn = createCallbackFcn(app, @imageextensionDropDownValueChanged, true);
            app.imageextensionDropDown.Value = '.tif';
            app.preparedataButton = uibutton(app.SetupTab, 'push');
            app.preparedataButton.ButtonPushedFcn = createCallbackFcn(app, @prepareDataButtonPushed, true);
            app.preparedataButton.Text = 'prepare data';
            app.workingLamp = uilamp(app.SetupTab);
            app.sizeXEditFieldLabel = uilabel(app.SetupTab);
            app.sizeXEditFieldLabel.Text = 'size X';
            app.sizeXEditField = uieditfield(app.SetupTab, 'numeric');
            app.sizeXEditField.ValueChangedFcn = createCallbackFcn(app, @sizeXEditFieldValueChanged, true);
            app.sizeYEditFieldLabel = uilabel(app.SetupTab);
            app.sizeYEditFieldLabel.Text = 'size Y';
            app.sizeYEditField = uieditfield(app.SetupTab, 'numeric');
            app.sizeYEditField.ValueChangedFcn = createCallbackFcn(app, @sizeYEditFieldValueChanged, true);
            app.bitdepthEditFieldLabel = uilabel(app.SetupTab);
            app.bitdepthEditFieldLabel.Text = 'bit depth';
            app.bitdepthEditField = uieditfield(app.SetupTab, 'numeric');
            app.bitdepthEditField.ValueChangedFcn = createCallbackFcn(app, @bitdepthEditFieldValueChanged, true);
            app.pixelsizeEditFieldLabel = uilabel(app.SetupTab);
            app.pixelsizeEditFieldLabel.Text = 'pixel size';
            app.pixelsizeEditField = uieditfield(app.SetupTab, 'numeric');
            app.pixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @pixelsizeEditFieldValueChanged, true);
            app.loadpreviousanalysisButton = uibutton(app.SetupTab, 'push');
            app.loadpreviousanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @loadpreviousanalysisButtonPushed, true);
            app.loadpreviousanalysisButton.Text = 'load previous analysis';
            app.workingLamp_3 = uilamp(app.SetupTab);
            app.loadimageinformationButton = uibutton(app.SetupTab, 'push');
            app.loadimageinformationButton.ButtonPushedFcn = createCallbackFcn(app, @loadimageinformationButtonPushed, true);
            app.loadimageinformationButton.Text = 'load image information';
            app.willloadfirstimagefromcontroldatasetLabel = uilabel(app.SetupTab);
            app.willloadfirstimagefromcontroldatasetLabel.Text = 'will load first image from control data set';
            app.Label = uilabel(app.SetupTab);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.SetupTab);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.SetupTab);
            app.Label_3.Text = '5';
            app.FilepathsLabel = uilabel(app.SetupTab);
            app.FilepathsLabel.Text = 'File paths';
            app.Label_10 = uilabel(app.SetupTab);
            app.Label_10.Text = '3';
            app.channel1nameEditFieldLabel = uilabel(app.SetupTab);
            app.channel1nameEditFieldLabel.Text = 'channel 1 name';
            app.channel1nameEditField = uieditfield(app.SetupTab, 'text');
            app.channel2nameEditFieldLabel = uilabel(app.SetupTab);
            app.channel2nameEditFieldLabel.Text = 'channel 2 name';
            app.channel2nameEditField = uieditfield(app.SetupTab, 'text');
            app.DNAchannelDropDownLabel = uilabel(app.SetupTab);
            app.DNAchannelDropDownLabel.Text = 'DNA channel';
            app.DNAchannelDropDown = uidropdown(app.SetupTab);
            app.DNAchannelDropDown.Items = {''};
            app.DNAchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @DNAchannelDropDownValueChanged, true);
            app.DNAchannelDropDown.Value = '';
            app.NETchannelDropDownLabel = uilabel(app.SetupTab);
            app.NETchannelDropDownLabel.Text = 'NET channel';
            app.NETchannelDropDown = uidropdown(app.SetupTab);
            app.NETchannelDropDown.Items = {''};
            app.NETchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @NETchannelDropDownValueChanged, true);
            app.NETchannelDropDown.Value = '';
            app.ChannelorderLabel = uilabel(app.SetupTab);
            app.ChannelorderLabel.Text = 'Channel order';
            app.Label_16 = uilabel(app.SetupTab);
            app.Label_16.Text = '4';
            app.batchallButton = uibutton(app.SetupTab, 'push');
            app.batchallButton.ButtonPushedFcn = createCallbackFcn(app, @batchallButtonPushed, true);
            app.batchallButton.Text = 'batch all';
            app.processallstepsLabel = uilabel(app.SetupTab);
            app.processallstepsLabel.Text = 'process all steps';
            app.workingLamp_9 = uilamp(app.SetupTab);
            app.Label_17 = uilabel(app.SetupTab);
            app.Label_17.Text = '5-12';
            app.mLabel = uilabel(app.SetupTab);
            app.mLabel.Text = 'µm';
            app.pixelsLabel_2 = uilabel(app.SetupTab);
            app.pixelsLabel_2.Text = 'pixels';
            app.pixelsLabel_3 = uilabel(app.SetupTab);
            app.pixelsLabel_3.Text = 'pixels';
            app.bitsLabel = uilabel(app.SetupTab);
            app.bitsLabel.Text = 'bits';
            app.PleaseseemanualforhowimagesshouldbeorganizedLabel = uilabel(app.SetupTab);
            app.PleaseseemanualforhowimagesshouldbeorganizedLabel.Text = 'Please see manual for how images should be organized';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.methodDropDownLabel = uilabel(app.SegmentationTab);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.SegmentationTab);
            app.methodDropDown.Items = {'adaptive', 'global', 'Edge', 'Chan-Vese'};
            app.methodDropDown.ValueChangedFcn = createCallbackFcn(app, @methodDropDownValueChanged, true);
            app.methodDropDown.Value = 'adaptive';
            app.sensitivityEditFieldLabel = uilabel(app.SegmentationTab);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.sensitivityEditField.Limits = [-Inf 1];
            app.sensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @sensitivityEditFieldValueChanged, true);
            app.sensitivityEditField.Value = 0.3;
            app.iterationsEditFieldLabel = uilabel(app.SegmentationTab);
            app.iterationsEditFieldLabel.Text = 'iterations';
            app.iterationsEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.iterationsEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsEditFieldValueChanged, true);
            app.iterationsEditField.Value = 100;
            app.calculatedEditFieldLabel = uilabel(app.SegmentationTab);
            app.calculatedEditFieldLabel.Text = 'calculated';
            app.calculatedEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.calculatedEditField.ValueChangedFcn = createCallbackFcn(app, @calculatedEditFieldValueChanged, true);
            app.calculatedEditField.Editable = 'off';
            app.usewatershedSwitchLabel = uilabel(app.SegmentationTab);
            app.usewatershedSwitchLabel.Text = 'use watershed';
            app.usewatershedSwitch = uiswitch(app.SegmentationTab, 'toggle');
            app.usewatershedSwitch.ValueChangedFcn = createCallbackFcn(app, @usewatershedSwitchValueChanged, true);
            app.segmentimagesButton = uibutton(app.SegmentationTab, 'push');
            app.segmentimagesButton.ButtonPushedFcn = createCallbackFcn(app, @segmentimagesButtonPushed, true);
            app.segmentimagesButton.Text = 'segment image(s)';
            app.workingLamp_2 = uilamp(app.SegmentationTab);
            app.batchSwitchLabel = uilabel(app.SegmentationTab);
            app.batchSwitchLabel.Text = 'batch';
            app.batchSwitch = uiswitch(app.SegmentationTab, 'slider');
            app.batchSwitch.Value = 'On';
            app.Label_4 = uilabel(app.SegmentationTab);
            app.Label_4.Text = '6';
            app.Label_6 = uilabel(app.SegmentationTab);
            app.Label_6.Text = '8';
            app.SegmentationandlabelsettingsLabel = uilabel(app.SegmentationTab);
            app.SegmentationandlabelsettingsLabel.Text = 'Segmentation and label settings';
            app.to1highervaluecanleadtohigherbackgroundLabel = uilabel(app.SegmentationTab);
            app.to1highervaluecanleadtohigherbackgroundLabel.Text = '0 to 1, higher value can lead to higher background';
            app.toseparatecloseobjectsLabel = uilabel(app.SegmentationTab);
            app.toseparatecloseobjectsLabel.Text = 'to separate close objects';
            app.segmentimagesButton_2 = uibutton(app.SegmentationTab, 'push');
            app.segmentimagesButton_2.ButtonPushedFcn = createCallbackFcn(app, @segmentimagesButton_2Pushed, true);
            app.segmentimagesButton_2.Text = 'segment image(s)';
            app.workingLamp_7 = uilamp(app.SegmentationTab);
            app.Label_12 = uilabel(app.SegmentationTab);
            app.Label_12.Text = '7';
            app.segmentcontrolsamplesLabel = uilabel(app.SegmentationTab);
            app.segmentcontrolsamplesLabel.Text = 'segment control samples';
            app.pixelsLabel = uilabel(app.SegmentationTab);
            app.pixelsLabel.Text = 'pixels';
            app.minimumareaEditField_2Label = uilabel(app.SegmentationTab);
            app.minimumareaEditField_2Label.Text = 'minimum area';
            app.minimumareaEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.minimumareaEditField.ValueChangedFcn = createCallbackFcn(app, @minimumareaEditFieldValueChanged, true);
            app.minimumareaEditField.Value = 40;
            app.m2Label = uilabel(app.SegmentationTab);
            app.m2Label.Text = 'µm2';
            app.ormorehighervalueincreasecomputationtimeLabel = uilabel(app.SegmentationTab);
            app.ormorehighervalueincreasecomputationtimeLabel.Text = '100 or more, higher value increase computation time';
            app.to1highervaluecanleadtohigherbackgroundLabel_2 = uilabel(app.SegmentationTab);
            app.to1highervaluecanleadtohigherbackgroundLabel_2.Text = '0 to 1, higher value can lead to higher background';
            app.toseparatecloseobjectsLabel_2 = uilabel(app.SegmentationTab);
            app.toseparatecloseobjectsLabel_2.Text = 'to separate close objects';
            app.pixelsLabel_4 = uilabel(app.SegmentationTab);
            app.pixelsLabel_4.Text = 'pixels';
            app.m2Label_2 = uilabel(app.SegmentationTab);
            app.m2Label_2.Text = 'µm2';
            app.ormorehighervalueincreasecomputationtimeLabel_2 = uilabel(app.SegmentationTab);
            app.ormorehighervalueincreasecomputationtimeLabel_2.Text = '100 or more, higher value increase computation time';
            app.methodDropDown_2Label = uilabel(app.SegmentationTab);
            app.methodDropDown_2Label.Text = 'method';
            app.methodDropDown_2 = uidropdown(app.SegmentationTab);
            app.methodDropDown_2.Items = {'adaptive', 'global', 'Edge', 'Chan-Vese'};
            app.methodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @methodDropDown_2ValueChanged, true);
            app.methodDropDown_2.Value = 'adaptive';
            app.sensitivityEditField_2Label = uilabel(app.SegmentationTab);
            app.sensitivityEditField_2Label.Text = 'sensitivity';
            app.sensitivityEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.sensitivityEditField_2.Limits = [-Inf 1];
            app.sensitivityEditField_2.ValueChangedFcn = createCallbackFcn(app, @sensitivityEditField_2ValueChanged, true);
            app.sensitivityEditField_2.Value = 0.2;
            app.iterationsEditField_2Label = uilabel(app.SegmentationTab);
            app.iterationsEditField_2Label.Text = 'iterations';
            app.iterationsEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.iterationsEditField_2.ValueChangedFcn = createCallbackFcn(app, @iterationsEditField_2ValueChanged, true);
            app.iterationsEditField_2.Value = 100;
            app.calculatedEditField_2Label = uilabel(app.SegmentationTab);
            app.calculatedEditField_2Label.Text = 'calculated';
            app.calculatedEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.calculatedEditField_2.ValueChangedFcn = createCallbackFcn(app, @calculatedEditField_2ValueChanged, true);
            app.calculatedEditField_2.Editable = 'off';
            app.usewatershedSwitch_2Label = uilabel(app.SegmentationTab);
            app.usewatershedSwitch_2Label.Text = 'use watershed';
            app.usewatershedSwitch_2 = uiswitch(app.SegmentationTab, 'toggle');
            app.usewatershedSwitch_2.ValueChangedFcn = createCallbackFcn(app, @usewatershedSwitch_2ValueChanged, true);
            app.minimumareaEditField_3Label = uilabel(app.SegmentationTab);
            app.minimumareaEditField_3Label.Text = 'minimum area';
            app.minimumareaEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.minimumareaEditField_2.ValueChangedFcn = createCallbackFcn(app, @minimumareaEditField_2ValueChanged, true);
            app.minimumareaEditField_2.Value = 60;
            app.DNAchannelLabel = uilabel(app.SegmentationTab);
            app.DNAchannelLabel.Text = 'DNA channel';
            app.NETchannelLabel = uilabel(app.SegmentationTab);
            app.NETchannelLabel.Text = 'NET channel';
            app.sensitivityanditerationsonlyapplytoadaptivemethodLabel = uilabel(app.SegmentationTab);
            app.sensitivityanditerationsonlyapplytoadaptivemethodLabel.Text = 'sensitivity and iterations only apply to adaptive method';
            app.sensitivityanditerationsonlyapplytoadaptivemethodLabel_2 = uilabel(app.SegmentationTab);
            app.sensitivityanditerationsonlyapplytoadaptivemethodLabel_2.Text = 'sensitivity and iterations only apply to adaptive method';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.getcellpropertiesButton = uibutton(app.AnalysisTab, 'push');
            app.getcellpropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @getcellpropertiesButtonPushed, true);
            app.getcellpropertiesButton.Text = 'get cell properties';
            app.batchSwitch_2Label = uilabel(app.AnalysisTab);
            app.batchSwitch_2Label.Text = 'batch';
            app.batchSwitch_2 = uiswitch(app.AnalysisTab, 'slider');
            app.batchSwitch_2.Value = 'On';
            app.workingLamp_4 = uilamp(app.AnalysisTab);
            app.determinethresholdButton = uibutton(app.AnalysisTab, 'push');
            app.determinethresholdButton.ButtonPushedFcn = createCallbackFcn(app, @determinethresholdButtonPushed, true);
            app.determinethresholdButton.Text = 'determine threshold';
            app.workingLamp_5 = uilamp(app.AnalysisTab);
            app.analysisofcontrolsamplesLabel = uilabel(app.AnalysisTab);
            app.analysisofcontrolsamplesLabel.Text = 'analysis of control samples';
            app.Label_7 = uilabel(app.AnalysisTab);
            app.Label_7.Text = '9';
            app.CellpropertiesLabel = uilabel(app.AnalysisTab);
            app.CellpropertiesLabel.Text = 'Cell properties';
            app.NETcriteriaLabel = uilabel(app.AnalysisTab);
            app.NETcriteriaLabel.Text = 'NET criteria';
            app.cellareafoldincreaseEditField_2Label = uilabel(app.AnalysisTab);
            app.cellareafoldincreaseEditField_2Label.Text = ' cell area fold increase';
            app.cellareafoldincreaseEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.cellareafoldincreaseEditField.ValueChangedFcn = createCallbackFcn(app, @areafoldincreaseEditFieldValueChanged, true);
            app.cellareafoldincreaseEditField.Value = 2;
            app.nucleideformationEditFieldLabel = uilabel(app.AnalysisTab);
            app.nucleideformationEditFieldLabel.Text = 'nuclei deformation';
            app.nucleideformationEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.nucleideformationEditField.ValueChangedFcn = createCallbackFcn(app, @nucleideformationEditFieldValueChanged, true);
            app.nucleideformationEditField.Value = 0.25;
            app.DNANETareaEditFieldLabel = uilabel(app.AnalysisTab);
            app.DNANETareaEditFieldLabel.Text = 'DNA/NET area';
            app.DNANETareaEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DNANETareaEditField.ValueChangedFcn = createCallbackFcn(app, @DNANETareaEditFieldValueChanged, true);
            app.DNANETareaEditField.Value = 0.7;
            app.analyzeNETsButton = uibutton(app.AnalysisTab, 'push');
            app.analyzeNETsButton.ButtonPushedFcn = createCallbackFcn(app, @generateresultsButtonPushed, true);
            app.analyzeNETsButton.Text = 'analyze NETs';
            app.workingLamp_8 = uilamp(app.AnalysisTab);
            app.Label_14 = uilabel(app.AnalysisTab);
            app.Label_14.Text = '11';
            app.batchSwitch_4Label = uilabel(app.AnalysisTab);
            app.batchSwitch_4Label.Text = 'batch';
            app.batchSwitch_4 = uiswitch(app.AnalysisTab, 'slider');
            app.batchSwitch_4.Value = 'On';
            app.NETsGauge_2Label = uilabel(app.AnalysisTab);
            app.NETsGauge_2Label.Text = 'NETs (%)';
            app.NETsGauge = uigauge(app.AnalysisTab, 'semicircular');
            app.UITable = uitable(app.AnalysisTab);
            app.UITable.ColumnName = {'image nr'; 'cell count'; 'NETs (%)'};
            app.UITable.RowName = {};
            app.CelldataLabel = uilabel(app.AnalysisTab);
            app.CelldataLabel.Text = 'Cell data';
            app.SummarystatisticsLabel = uilabel(app.AnalysisTab);
            app.SummarystatisticsLabel.Text = 'Summary statistics';
            app.cellcountEditField_2Label = uilabel(app.AnalysisTab);
            app.cellcountEditField_2Label.Text = 'cell count';
            app.cellcountEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.cellcountEditField.Editable = 'off';
            app.imagecountEditField_2Label = uilabel(app.AnalysisTab);
            app.imagecountEditField_2Label.Text = 'image count';
            app.imagecountEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.imagecountEditField.Editable = 'off';
            app.NETsEditField_2Label = uilabel(app.AnalysisTab);
            app.NETsEditField_2Label.Text = 'NETs (%)';
            app.NETsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.NETsEditField.Editable = 'off';
            app.Label_15 = uilabel(app.AnalysisTab);
            app.Label_15.Text = '10';
            app.controlNETsEditFieldLabel = uilabel(app.AnalysisTab);
            app.controlNETsEditFieldLabel.Text = 'control NETs (%)';
            app.controlNETsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.controlNETsEditField.Editable = 'off';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.workingLamp_6 = uilamp(app.OutputTab);
            app.Label_9 = uilabel(app.OutputTab);
            app.Label_9.Text = '12';
            app.batchSwitch_3Label = uilabel(app.OutputTab);
            app.batchSwitch_3Label.Text = 'batch';
            app.batchSwitch_3 = uiswitch(app.OutputTab, 'slider');
            app.batchSwitch_3.Value = 'On';
            app.outputresultsButton = uibutton(app.OutputTab, 'push');
            app.outputresultsButton.ButtonPushedFcn = createCallbackFcn(app, @outputresultsButtonPushed, true);
            app.outputresultsButton.Text = 'output results';
            app.csvCheckBox = uicheckbox(app.OutputTab);
            app.csvCheckBox.Text = 'csv';
            app.datadistributionandthresholdcutoffsCheckBox = uicheckbox(app.OutputTab);
            app.datadistributionandthresholdcutoffsCheckBox.Text = 'data distribution and threshold cutoffs';
            app.DatafilesLabel = uilabel(app.OutputTab);
            app.DatafilesLabel.Text = 'Data files';
            app.GraphsLabel = uilabel(app.OutputTab);
            app.GraphsLabel.Text = 'Graphs';
            app.methodsdescriptionincludingNETcriteriaCheckBox = uicheckbox(app.OutputTab);
            app.methodsdescriptionincludingNETcriteriaCheckBox.Text = 'methods description including NET criteria ';
            app.TextfilesLabel = uilabel(app.OutputTab);
            app.TextfilesLabel.Text = 'Text files';
            % Show the figure after all components are created
            app.NETQUANTv1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NETQUANT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NETQUANTv1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NETQUANTv1)
        end
    end
end